classdef Go < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        InterfaceTab              matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        RESETGAMEButton           matlab.ui.control.Button
        VerdesPanel               matlab.ui.container.Panel
        PasarButton               matlab.ui.control.Button
        RendirseButton            matlab.ui.control.Button
        JugarButton               matlab.ui.control.Button
        CapturasEditField_2Label  matlab.ui.control.Label
        CapturasEditField_2       matlab.ui.control.NumericEditField
        DropDown                  matlab.ui.control.DropDown
        NaranjasPanel             matlab.ui.container.Panel
        EditField_2               matlab.ui.control.EditField
        CapturasEditFieldLabel    matlab.ui.control.Label
        CapturasEditField         matlab.ui.control.NumericEditField
        TextArea                  matlab.ui.control.TextArea
        CapturaButton             matlab.ui.control.Button
        EditField_3               matlab.ui.control.EditField
        CALIBRATECAMERAButton     matlab.ui.control.Button
        ColorDetectionTab         matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        SerialandCalibrationTab   matlab.ui.container.Tab
        ComunicacinPanel          matlab.ui.container.Panel
        PuertoDropDownLabel       matlab.ui.control.Label
        Puerto                    matlab.ui.control.DropDown
        ConectarButton            matlab.ui.control.Button
        DesconectarButton         matlab.ui.control.Button
        RButton                   matlab.ui.control.Button
        NEXTButton                matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        BaudrateDropDownLabel     matlab.ui.control.Label
        Baudrate                  matlab.ui.control.DropDown
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        M = zeros(5,5); % Matriz de posiciones
        N = zeros(5,5);
        S = zeros(5,5);
        T = zeros(5,5);
        
        histo = strings(0); % Historia de la partida
        turn = 1;
        passWhite = 0;
        passBlack = 0;
        
        black = 0;
        white = 0;
        vid = 0;
        my_tform = 0;
        
        ardu = 0;
        
        calibrao = 0;
        
        x = 110;
        y = 400;
        z = 0;
        
        POS = zeros(5,5,3);
        llegadas = zeros(3,1);
        salidas = zeros(3,1);
    end
    
    methods (Access = private)
        
        function board(app)
            hold(app.UIAxes_2,'off');
            for i=1:5
                plot(app.UIAxes_2,[1 5],[i i],'-k');
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,[i i],[1 5],'k');
            end
            plot(app.UIAxes_2,[0 0],[0 6],'-w');
            plot(app.UIAxes_2,[6 0],[6 6],'-w');
            plot(app.UIAxes_2,[6 6],[0 6],'-w');
            plot(app.UIAxes_2,[0 6],[0 0],'-w');
            for i=1:5
                for j=1:5
                    if app.M(i,j) == 1
                        plot(app.UIAxes_2,i,j,'.r','MarkerSize',50);
                    elseif app.M(i,j) == -1
                        plot(app.UIAxes_2,i,j,'.g','MarkerSize',50);
                    end
                end
            end
            
            axis(app.UIAxes_2,'equal');
            
            text(app.UIAxes_2,1,1,'A','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,2,1,'B','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,3,1,'C','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,4,1,'D','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,5,1,'E','HorizontalAlignment','center','VerticalAlignment','top');
            
            text(app.UIAxes_2,1,1,'1','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,2,'2','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,3,'3','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,4,'4','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,5,'5','HorizontalAlignment','right','VerticalAlignment','middle');
            
            set(app.UIAxes_2,'visible','off')
        end
        
        function createInputFile(app,check,endgame)
            f = fopen("input.txt","w");
            fprintf(f,"boardsize 5\n");
            fprintf(f,"clear_board\n");
            
            for i=1:length(app.histo)
                fprintf(f,"play %s\n",app.histo(i));
            end
            if check == false
                fprintf(f,"genmove white\n");
                fprintf(f,"showboard\n");
            end
            if endgame == true
                fprintf(f,"final_score\n");
            end
            fclose(f);
        end
        
        function showBoard(app)
            str = sscanf(fileread('output.txt'),"%s");
            
            expression = '([.+OX]{5})';
            matchStr = cell2mat(regexp(str,expression,'match'));
            
            Mstr = [matchStr(1:5); matchStr(6:10); matchStr(11:15); matchStr(16:20); matchStr(21:25);];
            
            app.M = zeros(5,5);
            for i=1:5
                for j=1:5
                    if Mstr(i,j) == 'X'
                        app.M(i,j) = 1;
                    elseif Mstr(i,j) == 'O'
                        app.M(i,j) = -1;
                    end
                end
            end
            
            app.M = rot90(app.M,3);
            
            if sum(sum(app.N ~= 0))
                A = (app.N == 1) & (app.M == 0);
                [row,col] = find(A == 1);
                
                if length(row) ~= 0 && length(col) ~= 0
                    comer(app,row,col);
                end
            end
            
            app.N = app.M;
            
            expression = 'hascaptured(\d+)';
            matchStr = regexp(str,expression,'tokens');
            app.CapturasEditField_2.Value = str2double(cell2mat(matchStr{2}));
            app.CapturasEditField.Value   = str2double(cell2mat(matchStr{1}));
        end
        
        function [passed,resign] = playAI(app)
            passed = false; resign = false;
            
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s")
            
            if str(length(app.histo) + 4) ~= 'r' && str(length(app.histo) + 4) ~= 'P'
                i = str(length(app.histo) + 4);
                j = str(length(app.histo) + 5);
                app.histo(end+1) = sprintf("white %c%c", i, j);
                app.EditField_2.Value = sprintf("%c%c",i,j);
                c = 'ABCDE';
                
                %% ¿?¿?¿?¿?¿?¿?¿?¿?
                poner(app,find(c == i),str2num(j));
                              
                
            elseif str(length(app.histo) + 4) == 'r'
                resign = true;
            elseif str(length(app.histo) + 4) == 'P'
                passed = true;
            end
            
            
        end
        
        function ilegal = checkIlegal(app)
            ilegal = false;
            
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            if  str(length(app.histo) + 2) == '?'
                ilegal = true;
            end
            
        end
        
        function compute(app)
            createInputFile(app,false,false);
            [passed,resign] = playAI(app);
            if passed
                app.EditField_2.Value = "PASS";
                if app.passBlack == 1
                    endGame(app)
                end
            elseif resign
                pause(1);
                app.TextArea.Value = "NARANJA SE RINDEN";
            else
                app.passWhite = 0;
            end
            showBoard(app);
            board(app);
        end
        
        function endGame(app)
            createInputFile(app,false,true);
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            expression ='([WB])([+\d\.]+)$';
            matchStr = regexp(str,expression,'tokens');
            if matchStr{1}{1} == 'W'
                app.TextArea.Value = sprintf("GANAN VERDES %s",matchStr{1}{2});
            else
                app.TextArea.Value = sprintf("GANAN NARANJAS %s",matchStr{1}{2});
            end
            
            pause(3);
        end
        
        
        function M = getMatrix(app,J)
            lx = linspace(639/10,639*9/10,5);
            ly = linspace(480/10,480*9/10,5);
           [X,Y] = meshgrid(lx,ly);
            
            ob = 800;
            %% Detectar colores
            blackBand = J(:,:,1);
            whiteBand =  J(:,:,2);
            blueBand =  J(:,:,3);
            
            %% NEGRAS
            blackMask = (blackBand >= app.black.RL) & (blackBand <= app.black.RH);
            whiteMask = (whiteBand >= app.black.GL) & (whiteBand <= app.black.GH);
            blueMask = (blueBand >= app.black.BL) & (blueBand <= app.black.BH);
            objectsMask =blackMask & whiteMask & blueMask;
            
            % Detectar objetos
            % Eliminar bloques de color pequeños
            openBlocks = bwareaopen(objectsMask, ob);
            
            % figure
            % subplot(2,1,1);
            imshow(objectsMask,'Parent',app.UIAxes2_2); hold(app.UIAxes2_2,'on');
             plot(app.UIAxes2_2,X,Y,'.c');
            % Reconocer bloques y guardar posición y controno
            blackStats = regionprops(openBlocks, 'BoundingBox', 'Centroid');
             for object = 1:length(blackStats)
                 bb = blackStats(object).BoundingBox;
                 bc = blackStats(object).Centroid;
                 
                 rectangle(app.UIAxes2_2,'Position', bb, 'EdgeColor', 'r', 'LineWidth', 2);
                 plot(app.UIAxes2_2,bc(1),bc(2), '-m+');
             end
            
            %% BLANCAS
            
            blackMask = (blackBand >= app.white.RL) & (blackBand <= app.white.RH);
            whiteMask = (whiteBand >= app.white.GL) & (whiteBand <= app.white.GH);
            blueMask = (blueBand >= app.white.BL) & (blueBand <= app.white.BH);
            objectsMask =blackMask & whiteMask & blueMask;
            
            %subplot(2,1,2);
            imshow(objectsMask,'Parent',app.UIAxes2); hold(app.UIAxes2,'on');
            plot(app.UIAxes2,X,Y,'.c');
            
            % Detectar objetos
            % Eliminar bloques de color pequeños
            openBlocks = bwareaopen(objectsMask, ob);
            
            % Reconocer bloques y guardar posición y controno
            whiteStats = regionprops(openBlocks, 'BoundingBox', 'Centroid');
             for object = 1:length(whiteStats)
                 bb = whiteStats(object).BoundingBox;
                 bc = whiteStats(object).Centroid;
                 
                 rectangle(app.UIAxes2,'Position', bb, 'EdgeColor', 'g', 'LineWidth', 2);
                 plot(app.UIAxes2,bc(1),bc(2), '-m+');
             end
            
            
            %% Generar matriz
            M = zeros(5,5);
            % ingnoar etse nigwarng
            
            for object = 1:length(blackStats)
                bc = blackStats(object).Centroid;
                [~,i] = min(abs(bc(1)-lx));
                [~,j] = min(abs(bc(2)-ly));
                M(i,j) = 1;
                % ingnoar etse nigwarng
            end
            
            
            for object = 1:length(whiteStats)
                bc = whiteStats(object).Centroid;
                [~,i] = min(abs(bc(1)-lx));
                [~,j] = min(abs(bc(2)-ly));
                M(i,j) = -1;
                % ingnoar etse nigwarng
            end
            M = fliplr(M);
            % ingnoar etse nigwarng

            
      end
        
      function serialEvent(app)
            q = fscanf(app.ardu,'%f %f %f');
           
            q1 = q(1);
            q2 = q(2);
            q3 = q(3);

            q2r=deg2rad(q2);
            q3r=deg2rad(q3);

            [app.x,app.y,app.z] = directa(q1,q2r,q3r);
            
            app.XEditField.Value = app.x;
            app.YEditField.Value = app.y;
            app.ZEditField.Value = app.z;

      end
  
  function aparta(app)
        fprintf(app.ardu,"J1 M2 94\n");
        fprintf(app.ardu,"J1 M3 -70\n");
  end

        function poner(app,numeros, letras)
      for i = 1:length(letras)
          w = [app.POS(numeros(i),letras(i),1) app.POS(numeros(i),letras(i),2) app.POS(numeros(i),letras(i),3)];
          coge(app,[app.x,app.y,app.z],w);
          pause(2);
          aparta(app);
          pause(1);
      end
  end

        function comer(app,numeros, letras)

    for i = 1:length(letras)
        w = [app.POS(numeros(i),letras(i),1) app.POS(numeros(i),letras(i),2) app.POS(numeros(i),letras(i),3)];
        come(app,[app.x,app.y,app.z],w);
        pause(1);
        if i == length(letras)
            aparta(app);
            pause(1);
        end
    end
   end
    
   
   function move(app,x0,xf,tiempoh,p)
       
       % Muestrear en el espacio de la tarea
       x1 = linspace(x0(1),xf(1),p);
       x2 = linspace(x0(2),xf(2),p);
       x3 = linspace(x0(3),xf(3),p);
       
       % Muestrar el tiempo
       t = linspace(0,tiempoh,p);
       
       % Cinemática inversa de los puntos
       [q1,q2,q3] = inversa(x1,x2,x3);
       
       q2 = rad2deg(q2);
       q3 = rad2deg(q3);
       
       % Obtener los polinomios para coordenada articular con pendiente nula al
       % principio y al final
       pp1 = spline(t,[0 q1 0]);
       pp2 = spline(t,[0 q2 0]);
       pp3 = spline(t,[0 q3 0]);
       
       % Generar mensaje en formato  d1 d2 d3 c1 c2 c3 b1 b2 b3 a1 a2 a3 t1 t2 t3
       str1 = sprintf(' %3.3f',[pp1.coefs(:);t(2:end)']);
       str2 = sprintf(' %3.3f',[pp2.coefs(:);t(2:end)']);
       str3 = sprintf(' %3.3f',[pp3.coefs(:);t(2:end)']);
       
       command = sprintf('J5 %d%s_%d%s_%d%s_\n', p-1, str1, p-1, str2, p-1, str3);
       
       fprintf(app.ardu,command);
   end
    
   function coge(app,ahora, hasta)
       
       offset = 30;
       p = 3;
       tiempho = 10;
       
       wp1 = ahora;
       %%Punto a ir para recoger ficha
        x2 = app.salidas(1);
        y2 = app.salidas(2);
        z2 = app.salidas(3);
       wp2 = [x2,y2,z2]; wp2(3) = wp2(3) + offset;
       wp3 = [x2,y2,z2];
       %%Punto para colocar ficha
       wp4 = hasta; wp4(3) = wp4(3) + offset;
       wp5 = hasta;
       move(app,wp1,wp2,tiempho,p);      pause(tiempho);
       fprintf(app.ardu,"J4 M2 255\n");
       move(app,wp2,wp3,tiempho/5,p);      pause(tiempho/5);
       move(app,wp3,wp2,tiempho/5,p);      pause(tiempho/5);
       
       move(app,wp2,wp4,tiempho,p);      pause(tiempho);
       
       move(app,wp4,wp5,tiempho/5,p);      pause(tiempho/5);
       fprintf(app.ardu,"J4 M2 0\n");
       move(app,wp5,wp4,tiempho/5,p);      pause(tiempho/5);
   end

   function come(app,ahora,desde)
       offset = 30;
       p = 3;
       esperini = 10;
       
       [x2,y2,z2] = directa(220,deg2rad(50),deg2rad(-96));
       wp1 = ahora;
       %%Punto a ir para recoger ficha
       wp2 = desde; wp2(2) = wp2(2) - 15;   wp2(3) = wp2(3) + offset;
       wp3 = desde;
       %Punto donde se deja la ficha pasta
       wp4 = [x2,y2,z2]; wp4(3) = wp4(3) + offset;
       
       
       move(app,wp1,wp2,esperini,p);      pause(esperini);
       fprintf(app.ardu,"J4 M2 255\n");
       move(app,wp2,wp3,esperini/4,p);      pause(esperini/4);
       move(app,wp3,wp2,esperini/4,p);      pause(esperini/4);
       
       move(app,wp2,wp4,esperini,p);      pause(esperini);
       
       fprintf(app.ardu,"J4 M2 0\n");
       
   end

    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Puerto.Items=seriallist
            
            app.Baudrate.Items = {'9600','115200'};
            app.Baudrate.Value = {'115200'};
        end

        % Button pushed function: RESETGAMEButton
        function RESETGAMEButtonPushed(app, event)
            app.M = zeros(5,5); % Matriz de posiciones
            app.histo = strings(0,0); % Historia de la partida
            app.turn = 1;
            board(app);
            app.TextArea.Value = "";
            
        end

        % Button pushed function: JugarButton
        function JugarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.TextArea.Value = "";
                
                app.histo(end+1) = sprintf("black %s", app.DropDown.Value);
                createInputFile(app,true,false);
                ilegal = checkIlegal(app);
                
                if ilegal
                    app.histo(end) = [];
                    app.turn = 1;
                    app.TextArea.Value = "ILEGAL";
                    return;
                end
                app.passBlack = 0;
                compute(app)
                app.turn = 1;
            end
        end

        % Button pushed function: PasarButton
        function PasarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.passBlack = 1;
                if app.passWhite == 1
                    endGame(app);
                end
                compute(app);
                app.turn = 1;
            end
        end

        % Button pushed function: RendirseButton
        function RendirseButtonPushed(app, event)
            app.TextArea.Value = "VERDES SE RINDEN";
        end

        % Button pushed function: CapturaButton
        function CapturaButtonPushed(app, event)

            
            im =snapshot(app.vid);
            
            J = imwarp(im,app.my_tform,'cubic','OutputView', imref2d( size(im) ));
            imshow(J,'Parent',app.UIAxes);
            
 
           app.S = getMatrix(app,J);

           app.S = rot90(app.S);
           app.S           
           if sum(sum(app.T ~= 0))
                A = (app.S == 1) & (app.T == 0)
                [row,col] = find(A == 1);
                c = 'ABCDE';
                % ingnoar etse nigwarng
                if length(row) ~= 0 && length(col) ~= 0
                    app.EditField_3.Value = sprintf("N to %c%d\n",c(row),col);
                    
                   if app.turn == 1
                    app.turn = 0;
                    app.TextArea.Value = "";
                
                    app.histo(end+1) = sprintf("black %c%d", c(row),col);
                    app.histo
                    createInputFile(app,true,false);
                    ilegal = checkIlegal(app);
                
                    if ilegal
                        app.histo(end) = [];
                        app.turn = 1;
                        app.TextArea.Value = "ILEGAL";
                        return;
                    end
                    app.passBlack = 0;
                    compute(app);
                    app.turn = 1;
                    end
               end  
           end
           
           app.T = app.S;


        end

        % Button pushed function: CALIBRATECAMERAButton
        function CALIBRATECAMERAButtonPushed(app, event)
            
            if isnumeric(app.vid)
                % Buscar camaras
                cameras = strcmp('Webcam C170: Webcam C170',webcamlist);
           
                % Buscar camara1
                for i=1:length(webcamlist)
                    if cameras(i)
                        app.vid = webcam(i);
                        break;
                    end
                end
            end     
            
            preview( app.vid);
            
            tic
            while toc < 2
                            im =snapshot(app.vid);
                            pause(0.1);
            end


            im =snapshot(app.vid);
            
            figure
            % ingnoar etse nigwarng
            imshow(im); 
            % ingnoar etse nigwarng
            title('Selecciona las esquinas y pulsa enter');
            [x,y] = getpts;
            a = [x y];
            title('');
            % ingnoar etse nigwarng
            b = (  ((a - mean(a)) > 0) .* [639 479]  ) +1 ; % Maximizar
            app.my_tform    = fitgeotrans(a,b,'projective'); % Matriz de transformación
            
            J = imwarp(im,app.my_tform,'cubic','OutputView', imref2d( size(im) ));
            imshow(J,'Parent',app.UIAxes);


            imshow(J);
            % ingnoar etse nigwarng
            title('Selecciona ficha naranja y pulsa enter');
            % ingnoar etse nigwarng
            [x,y] = getpts;
            r = impixel(J,round(x),round(y));
            threshold = 30;
            app.black = struct('RL',r(1)-threshold,'RH',r(1)+threshold,...
                'GL',r(2)-threshold,'GH',r(2)+threshold,...
                'BL',r(3)-threshold,'BH',r(3)+threshold);
            % ingnoar etse nigwarng
            title('Selecciona algo verde y pulsa enter');
            [x,y] = getpts;
            v = impixel(J,round(x),round(y));
            app.white = struct('RL',v(1)-threshold,'RH',v(1)+threshold,...
                'GL',v(2)-threshold,'GH',v(2)+threshold,...
                'BL',v(3)-threshold,'BH',v(3)+threshold);
            close all

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           try 
            fclose(app.ardu);
            delete(app.vid);
            clear app.vid
           catch
           end
            delete(app)
            clear app
           
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            app.ardu=serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
            app.ardu.Terminator = 'LF';
            app.ardu.BytesAvailableFcnMode =  'terminator';
            app.ardu.BytesAvailableFcn = @(~,~) app.serialEvent;
            fopen(app.ardu);

            set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
            set(app.DesconectarButton,'BackgroundColor',[0.96,0.96,0.96]);
            pause(2);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
                        app.Puerto.Items=seriallist;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
                            fclose(app.ardu);
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
                                          disp(app.y);

            switch app.calibrao
                    case 0
                        app.TextArea_2.Value = "PULSA HOME";
                    case 1
                         fprintf(app.ardu,"J0\n");
                         
                         %%%%%%%%%%%
                                                  
                         app.TextArea_2.Value = "ESPERA HOME, NEXT DESPUES";
                    case 2
                         fprintf(app.ardu, "J1 M1 25\n");
                         
                         %%%%%%%%%%%%
                         
                         app.TextArea_2.Value = "ESPERA, NEXT DESPUES \n MODO GUIADO";
                    case 3
                         fprintf(app.ardu, "J6\n");
                         
                         %%%%%%%%%%%%
                         
                         app.TextArea_2.Value = "LLEVA A PUNTO 1 Y NEXT";                         
                    case 4
                          app.POS(1,1,1) = app.x;
                          app.POS(1,1,2) = app.y;
                          app.POS(1,1,3) = app.z;
                          %%%%%%%%%%%%
                          
                          app.TextArea_2.Value = "LLEVA A PUNTO 2 Y NEXT";  
                          
                    case 5
                          app.POS(1,5,1) = app.x;
                          app.POS(1,5,2) = app.y;
                          app.POS(1,5,3) = app.z;
                          pause(1);
                          
                          fprintf(app.ardu, "J6\n"); pause(1);
                          fprintf(app.ardu, "J1 M1 225\N\n");
                         
                         %%%%%%%%%%%%
                         
                         app.TextArea_2.Value = "ESPERA, NEXT DESPUES \n MODO GUIADO";   
                    case 6
                         fprintf(app.ardu, "J6\n");
                         
                         %%%%%%%%%%%%

                         app.TextArea_2.Value = "LLEVA A PUNTO 3 Y NEXT";  
                    case 7                         
                          app.POS(5,1,1) = app.x;
                          app.POS(5,1,2) = app.y;
                          app.POS(5,1,3) = app.z;
                         %%%%%%%%%%%%                        
                     
                         app.TextArea_2.Value = "LLEVA A SALIDAS Y NEXT";                          
                    case 8 
                          app.salidas(1) = app.x;
                          app.salidas(2) = app.y;
                          app.salidas(3) = app.z;                         
                         %%%%%%%%%%%%

                         app.TextArea_2.Value = "LLEVA A LLEGADAS Y NEXT";
                    case 9
                          app.llegadas(1) = app.x;
                          app.llegadas(2) = app.y;
                          app.llegadas(3) = app.z;  
                          
                         %%%%%%%%%%%%
                         app.TextArea_2.Value = "Pulsa para des-harcodear";
%                          app.POS(1,1,1) = 237; app.POS(1,2,1) = 174; app.POS(1,3,1) = 121; app.POS(1,4,1) = 63; app.POS(1,5,1) = 5;
%                          app.POS(2,1,1) = 237; app.POS(2,2,1) = 174; app.POS(2,3,1) = 121; app.POS(2,4,1) = 63; app.POS(2,5,1) = 5;
%                          app.POS(3,1,1) = 237; app.POS(3,2,1) = 174; app.POS(3,3,1) = 121; app.POS(3,4,1) = 63; app.POS(3,5,1) = 5;
%                          app.POS(4,1,1) = 237; app.POS(4,2,1) = 174; app.POS(4,3,1) = 121; app.POS(4,4,1) = 63; app.POS(4,5,1) = 5;
%                          app.POS(5,1,1) = 237; app.POS(5,2,1) = 174; app.POS(5,3,1) = 121; app.POS(5,4,1) = 63; app.POS(5,5,1) = 5;
%                          
%                          app.POS(1,1,2) = 232.0425; app.POS(1,2,2) = 232.0425; app.POS(1,3,2) = 232.0425; app.POS(1,4,2) = 232.0425; app.POS(1,5,2) = 232.0425;
%                          app.POS(2,1,2) = 278.8285; app.POS(2,2,2) = 278.8285; app.POS(2,3,2) = 278.8285; app.POS(2,4,2) = 278.8285; app.POS(2,5,2) = 278.8285;
%                          app.POS(3,1,2) = 335.6144; app.POS(3,2,2) = 335.6144; app.POS(3,3,2) = 335.6144; app.POS(3,4,2) = 335.6144; app.POS(3,5,2) = 335.6144;
%                          app.POS(4,1,2) = 397.4003; app.POS(4,2,2) = 397.4003; app.POS(4,3,2) = 397.4003; app.POS(4,4,2) = 397.4003; app.POS(4,5,2) = 397.4003;
%                          app.POS(5,1,2) = 459.1863; app.POS(5,2,2) = 459.1863; app.POS(5,3,2) = 459.1863; app.POS(5,4,2) = 459.1863; app.POS(5,5,2) = 459.1863;
%                          
%                          app.POS(1,1,3) = -80; app.POS(1,2,3) = -80; app.POS(1,3,3) = -80; app.POS(1,4,3) = -80; app.POS(1,5,3) = -80;
%                          app.POS(2,1,3) = -80; app.POS(2,2,3) = -80; app.POS(2,3,3) = -80; app.POS(2,4,3) = -80; app.POS(2,5,3) = -80;
%                          app.POS(3,1,3) = -80; app.POS(3,2,3) = -80; app.POS(3,3,3) = -80; app.POS(3,4,3) = -80; app.POS(3,5,3) = -80;
%                          app.POS(4,1,3) = -80; app.POS(4,2,3) = -80; app.POS(4,3,3) = -80; app.POS(4,4,3) = -80; app.POS(4,5,3) = -80;
%                          app.POS(5,1,3) = -80; app.POS(5,2,3) = -80; app.POS(5,3,3) = -80; app.POS(5,4,3) = -80; app.POS(5,5,3) = -80;
                         pause(1);
                         fprintf(app.ardu, "J6\n"); pause(0.1);
                         aparta(app);
                    case 10
                         xx = linspace(app.POS(1,1,1),app.POS(5,1,1),5);
                         yy = linspace(app.POS(1,1,2),app.POS(1,5,2),5);
                        
                    
                         [X, Y] = meshgrid(xx,yy);
                         app.POS(:,:,1)=fliplr(X);
                         app.POS(:,:,2)=Y;
                         app.POS(:,:,3)=-80;
                         
                         
                         app.POS


                end
                
                app.calibrao = app.calibrao + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.UIAxes = uiaxes(app.InterfaceTab);
            app.UIAxes_2 = uiaxes(app.InterfaceTab);
            app.RESETGAMEButton = uibutton(app.InterfaceTab, 'push');
            app.RESETGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESETGAMEButtonPushed, true);
            app.RESETGAMEButton.Text = 'RESET GAME';
            app.VerdesPanel = uipanel(app.InterfaceTab);
            app.VerdesPanel.Title = 'Verdes';
            app.PasarButton = uibutton(app.VerdesPanel, 'push');
            app.PasarButton.ButtonPushedFcn = createCallbackFcn(app, @PasarButtonPushed, true);
            app.PasarButton.Text = 'Pasar';
            app.RendirseButton = uibutton(app.VerdesPanel, 'push');
            app.RendirseButton.ButtonPushedFcn = createCallbackFcn(app, @RendirseButtonPushed, true);
            app.RendirseButton.Text = 'Rendirse';
            app.JugarButton = uibutton(app.VerdesPanel, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @JugarButtonPushed, true);
            app.JugarButton.Text = 'Jugar';
            app.CapturasEditField_2Label = uilabel(app.VerdesPanel);
            app.CapturasEditField_2Label.Text = 'Capturas';
            app.CapturasEditField_2 = uieditfield(app.VerdesPanel, 'numeric');
            app.CapturasEditField_2.ValueDisplayFormat = '%.0f';
            app.DropDown = uidropdown(app.VerdesPanel);
            app.DropDown.Items = {'A1', 'A2', 'A3', 'A4', 'A5', 'B1', 'B2', 'B3', 'B4', 'B5', 'C1', 'C2', 'C3', 'C4', 'C5', 'D1', 'D2', 'D3', 'D4', 'D5', 'E1', 'E2', 'E3', 'E4', 'E5'};
            app.DropDown.Value = 'A1';
            app.NaranjasPanel = uipanel(app.InterfaceTab);
            app.NaranjasPanel.Title = 'Naranjas';
            app.EditField_2 = uieditfield(app.NaranjasPanel, 'text');
            app.CapturasEditFieldLabel = uilabel(app.NaranjasPanel);
            app.CapturasEditFieldLabel.Text = 'Capturas';
            app.CapturasEditField = uieditfield(app.NaranjasPanel, 'numeric');
            app.CapturasEditField.ValueDisplayFormat = '%.0f';
            app.TextArea = uitextarea(app.InterfaceTab);
            app.CapturaButton = uibutton(app.InterfaceTab, 'push');
            app.CapturaButton.ButtonPushedFcn = createCallbackFcn(app, @CapturaButtonPushed, true);
            app.CapturaButton.Text = 'Captura';
            app.EditField_3 = uieditfield(app.InterfaceTab, 'text');
            app.CALIBRATECAMERAButton = uibutton(app.InterfaceTab, 'push');
            app.CALIBRATECAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATECAMERAButtonPushed, true);
            app.CALIBRATECAMERAButton.Text = 'CALIBRATE CAMERA';
            app.ColorDetectionTab = uitab(app.TabGroup);
            app.ColorDetectionTab.Title = 'Color Detection';
            app.UIAxes2 = uiaxes(app.ColorDetectionTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.ColorDetectionTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.SerialandCalibrationTab = uitab(app.TabGroup);
            app.SerialandCalibrationTab.Title = 'Serial and Calibration';
            app.ComunicacinPanel = uipanel(app.SerialandCalibrationTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.ConectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.RButton = uibutton(app.ComunicacinPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.NEXTButton = uibutton(app.SerialandCalibrationTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.TextArea_2 = uitextarea(app.SerialandCalibrationTab);
            app.TextArea_2.Value = {'MODIFICA'};
            app.BaudrateDropDownLabel = uilabel(app.SerialandCalibrationTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.SerialandCalibrationTab);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
            app.XEditFieldLabel = uilabel(app.SerialandCalibrationTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SerialandCalibrationTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.SerialandCalibrationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SerialandCalibrationTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.SerialandCalibrationTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.SerialandCalibrationTab, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Go

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end