classdef Panel_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PaneldecontrolTab            matlab.ui.container.Tab
        HomeButton                   matlab.ui.control.Button
        CadenaabiertaPanel           matlab.ui.container.Panel
        V2                           matlab.ui.control.Slider
        V3                           matlab.ui.control.Slider
        V1                           matlab.ui.control.Slider
        q1mmsLabel                   matlab.ui.control.Label
        q2gradsLabel                 matlab.ui.control.Label
        q3gradsLabel                 matlab.ui.control.Label
        STOPButton                   matlab.ui.control.Button
        CadenacerradaPanel           matlab.ui.container.Panel
        q1mmLabel                    matlab.ui.control.Label
        q2degLabel                   matlab.ui.control.Label
        q3degLabel                   matlab.ui.control.Label
        posq1                        matlab.ui.control.Slider
        posq2                        matlab.ui.control.Slider
        posq3                        matlab.ui.control.Slider
        posq4                        matlab.ui.control.Slider
        q4degLabel                   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ComunicacinPanel             matlab.ui.container.Panel
        PuertoDropDownLabel          matlab.ui.control.Label
        Puerto                       matlab.ui.control.DropDown
        ConectarButton               matlab.ui.control.Button
        DesconectarButton            matlab.ui.control.Button
        RButton_2                    matlab.ui.control.Button
        CartesianasPanel             matlab.ui.container.Panel
        XEditFieldLabel              matlab.ui.control.Label
        X                            matlab.ui.control.NumericEditField
        YLabel_3                     matlab.ui.control.Label
        Y                            matlab.ui.control.NumericEditField
        ZLabel_3                     matlab.ui.control.Label
        Z                            matlab.ui.control.NumericEditField
        CadenaSwitch                 matlab.ui.control.ToggleSwitch
        VentosaPanel                 matlab.ui.container.Panel
        Switch                       matlab.ui.control.Switch
        GarraPanel                   matlab.ui.container.Panel
        AbrirButton                  matlab.ui.control.Button
        CerrarButton                 matlab.ui.control.Button
        MS                           matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        SteppersSwitchLabel          matlab.ui.control.Label
        SteppersSwitch               matlab.ui.control.Switch
        LinearectaapuntoencartesianasPanel  matlab.ui.container.Panel
        EnviarButton                 matlab.ui.control.Button
        XSpinnerLabel                matlab.ui.control.Label
        XSpinner                     matlab.ui.control.Spinner
        YSpinnerLabel                matlab.ui.control.Label
        YSpinner                     matlab.ui.control.Spinner
        ZSpinnerLabel                matlab.ui.control.Label
        ZSpinner                     matlab.ui.control.Spinner
        PuntosSpinnerLabel           matlab.ui.control.Label
        PuntosSpinner                matlab.ui.control.Spinner
        TiempoSpinnerLabel           matlab.ui.control.Label
        TiempoSpinner                matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        alfaSpinner                  matlab.ui.control.Spinner
        ArticularesPanel             matlab.ui.container.Panel
        q1mmLabel_2                  matlab.ui.control.Label
        q1p                          matlab.ui.control.NumericEditField
        q2degLabel_2                 matlab.ui.control.Label
        q2p                          matlab.ui.control.NumericEditField
        q3degLabel_2                 matlab.ui.control.Label
        q3p                          matlab.ui.control.NumericEditField
        CadenacerradaPanel_2         matlab.ui.container.Panel
        xmmLabel                     matlab.ui.control.Label
        ydegLabel                    matlab.ui.control.Label
        zdegLabel                    matlab.ui.control.Label
        posq1_2                      matlab.ui.control.Slider
        posq2_2                      matlab.ui.control.Slider
        posq3_2                      matlab.ui.control.Slider
        SetTCPPanel                  matlab.ui.container.Panel
        GarraButton                  matlab.ui.control.Button
        VentosaButton                matlab.ui.control.Button
        ErrorPanel                   matlab.ui.container.Panel
        Lamp_Tr                      matlab.ui.control.Lamp
        BaudrateDropDownLabel        matlab.ui.control.Label
        Baudrate                     matlab.ui.control.DropDown
        q1t                          matlab.ui.control.UIAxes
        q2t                          matlab.ui.control.UIAxes
        q3t                          matlab.ui.control.UIAxes
        PendantTab                   matlab.ui.container.Tab
        IniciarGuiadoButton          matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        MarcarPuntoButton            matlab.ui.control.Button
        EliminarPuntoAnteriorButton  matlab.ui.control.Button
        PosicinPanel                 matlab.ui.container.Panel
        CartesianasPanel_2           matlab.ui.container.Panel
        XEditFieldLabel_2            matlab.ui.control.Label
        X_2                          matlab.ui.control.NumericEditField
        YLabel_4                     matlab.ui.control.Label
        Y_2                          matlab.ui.control.NumericEditField
        ZLabel_4                     matlab.ui.control.Label
        Z_2                          matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        alfa_2                       matlab.ui.control.NumericEditField
        ArticularesPanel_2           matlab.ui.container.Panel
        q3degLabel_3                 matlab.ui.control.Label
        q3p_2                        matlab.ui.control.NumericEditField
        q1mmLabel_3                  matlab.ui.control.Label
        q1p_2                        matlab.ui.control.NumericEditField
        q2degLabel_3                 matlab.ui.control.Label
        q2p_2                        matlab.ui.control.NumericEditField
        q4degLabel_3                 matlab.ui.control.Label
        q4p_2                        matlab.ui.control.NumericEditField
        Panel_2                      matlab.ui.container.Panel
        PuntosmarcadosTextAreaLabel  matlab.ui.control.Label
        PuntosmarcadosTextArea       matlab.ui.control.TextArea
        IniciarMovimientoButton      matlab.ui.control.Button
    end


    properties (Access = public)
        myTimer = 0; % Timer periodico
        arduino = 0; % Puerto serial
        timeS = 0;
        Obarra1A = stlread('Barra2.stl');
        Obarra1B = stlread('Barra.stl');
        Obarra1C = stlread('Barra2.stl');
        Obarra2A = stlread('Barra2.stl');
        Oenlace = stlread('Enlace.stl');
        Obarra2B = stlread('Barra.stl');
        Otriangulo = stlread('Middle.stl');
        Otoolhead = stlread('Toolhead.stl');
        Opatin = stlread('Slider.stl');
        Obase = stlread('Base.stl');
        
        nowX = 0;
        nowY = 0;
        nowZ = 0;
        light = 0;
        q1tr=NaN(100,1);
        q2tr=NaN(100,1);
        q3tr=NaN(100,1);
        ttr=NaN(100,1);
        f;
        i=1;

    end
    
    methods (Access = public)
        
        function main(app)
            
            if app.CadenaSwitch.Value=="Abierta"
                app.V1.Enable = 'on';
                app.V2.Enable = 'on';
                app.V3.Enable = 'on';
                app.q1mmsLabel.Enable = 'on';
                app.q2gradsLabel.Enable = 'on';
                app.q3gradsLabel.Enable = 'on';
                
                
                app.posq1.Enable = 'off';
                app.posq2.Enable = 'off';
                app.posq3.Enable = 'off';
                app.posq4.Enable = 'off';
                app.q1mmLabel.Enable = 'off';
                app.q2degLabel.Enable = 'off';
                app.q3degLabel.Enable = 'off';
                app.q4degLabel.Enable = 'off';
                app.posq1_2.Enable = 'off';
                app.posq2_2.Enable = 'off';
                app.posq3_2.Enable = 'off';
                app.xmmLabel.Enable = 'off';
                app.ydegLabel.Enable = 'off';
                app.zdegLabel.Enable = 'off';
                
            end
            
            if app.CadenaSwitch.Value=="Cerrada"
                
                
                % Detener movimiento
                try
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 1, 0)); pause(0.1);
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 2, 0)); pause(0.1);
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 3, 0)); pause(0.1);
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
                app.V1.Enable = 'off';
                app.V2.Enable = 'off';
                app.V3.Enable = 'off';
                app.q1mmsLabel.Enable = 'off';
                app.q2gradsLabel.Enable = 'off';
                app.q3gradsLabel.Enable = 'off';
                app.posq1.Enable = 'on';
                app.posq2.Enable = 'on';
                app.posq3.Enable = 'on';
                app.posq4.Enable = 'on';
                app.q1mmLabel.Enable = 'on';
                app.q2degLabel.Enable = 'on';
                app.q3degLabel.Enable = 'on';
                app.q4degLabel.Enable = 'on';
                app.posq1_2.Enable = 'on';
                app.posq2_2.Enable = 'on';
                app.posq3_2.Enable = 'on';
                app.xmmLabel.Enable = 'on';
                app.ydegLabel.Enable = 'on';
                app.zdegLabel.Enable = 'on';
            end


        end
        
        function consultarAngulos(app,~) % Callback para el timer
            timeN = second(datetime('now'));
         
            if timeN - app.timeS < 1
                %pause(0.001);
                return;
            end    
        end
        
    end

    methods (Access = private)
    
        function serialEvent(app)
            q = fscanf(app.arduino,'%f %f %f');
            
     
            q1 = q(1);
            q2 = q(2);
            q3 = q(3);

            q2r=deg2rad(q2);
            q3r=deg2rad(q3);

            app.q1p.Value = q1;
            app.q2p.Value = q2;
            app.q3p.Value = q3;
            app.q1p_2.Value = q1;
            app.q2p_2.Value = q2;

            [x2,y2,z2,x1,y1,z1] = directa(q1,q2r,q3r);
            app.X.Value = x2;
            app.Y.Value = y2;
            app.Z.Value = z2;
            app.X_2.Value = x2;
            app.Y_2.Value = y2;
            app.Z_2.Value = z2;
            app.nowX=x2;
            app.nowY=y2;
            app.nowZ=z2;

            
            %GRÁFICA TRAYECTORIA REAL
            
            if(toc<app.TiempoSpinner.Value)
                
                app.q1tr(app.i)=q1;
                app.q2tr(app.i)=q2;
                app.q3tr(app.i)=q3;
                app.ttr(app.i) = toc;
                ii = isfinite(app.q1tr);
                
                hold(app.q1t,'on');
                hold(app.q2t,'on');
                hold(app.q3t,'on');
                
                plot(app.q1t,app.ttr(ii),app.q1tr(ii),'-');
                plot(app.q2t,app.ttr(ii),app.q2tr(ii),'-');
                plot(app.q3t,app.ttr(ii),app.q3tr(ii),'-');
                
              %  figure(1);                
              %  [xx,yy,zz] = directa(app.q1tr(ii),deg2rad(app.q2tr(ii)),deg2rad(app.q3tr(ii)));
              %  plot3(xx,yy,zz,'-k');
                app.i=(app.i+1);
                
            end
            
                hold(app.q1t,'off');
                hold(app.q2t,'off');
                hold(app.q3t,'off');
            
            % PLOT
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            
            barra1A = app.Obarra1A;
            barra1B = app.Obarra1B;
            barra1C = app.Obarra1C;
            barra2A = app.Obarra2A;
            enlace = app.Oenlace;
            barra2B = app.Obarra2B;
            triangulo = app.Otriangulo;
            toolhead = app.Otoolhead;
            patin = app.Opatin;
            base = app.Obase;
            
     
            b01A = barra1A.vertices;
            b01B = barra1B.vertices;
            b01C = barra1C.vertices;
            b02A = barra2A.vertices;
            enl = enlace.vertices;
            b02B = barra2B.vertices;
            t = triangulo.vertices;
            th = toolhead.vertices;
            p = patin.vertices;
            ba = base.vertices;
                 
            view(app.UIAxes, 3)
            
            %% Barra 1 A (doble)
            % Rotacion del brazo
            G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
            % Posicionamiento y traslacion
            T = [q1-29 0 0];
            
            barra1A.vertices = (G*b01A')'+ T;
            
            patch(app.UIAxes, barra1A,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Barra 1 B (simple)
            % Rotacion del brazo
            G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
            % Posicionamiento y traslacion
            T = [(q1-5) -50 50];
            
            barra1B.vertices = (G*b01B')'+ T;
            
            patch(app.UIAxes, barra1B,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Barra 1 C (Doble)
            % Rotacion del brazo
            G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
            % Posicionamiento y traslacion
            T = [q1-29 (40*cos(pi+q3r)) (40*sin(pi+q3r))];
            
            barra1C.vertices = (G*b01C')'+ T;
            
            patch(app.UIAxes, barra1C,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Barra 2 A (Doble)
            % Rotacion del brazo
            G = [1 0 0; 0 cos(q3r) -sin(q3r); 0 sin(q3r) cos(q3r)];
            % Posicionamiento y traslacion
            T = [q1-29 (cos(q2r)*300) (sin(q2r)*300)];
            
            barra2A.vertices = (G*b02A')'+ T;
            
            patch(app.UIAxes, barra2A,'FaceColor', [0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            %% Enlace (Doble)
            % Rotacion del enlace
            G = [1 0 0; 0 cos(pi+q3r) -sin(pi+q3r); 0 sin(pi+q3r) cos(pi+q3r)];
            % Posicionamiento y traslacion
            T = [q1-29 (cos(q2r)*300-10*sin(q3r)) (sin(q2r)*300+10*cos(q3r))];
            
            enlace.vertices = (G*enl')'+ T;
            
            patch(app.UIAxes, enlace,'FaceColor',[0.8500, 0.3250, 0.0980],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            
            %% Barra 2 B (Simple)
            % Rotacion del brazo
            G = [1 0 0; 0 cos(q3r) -sin(q3r); 0 sin(q3r) cos(q3r)];
            % Posicionamiento y traslacion
            T = [(q1-5) (cos(q2r)*300+50) (sin(q2r)*300+50)];
            
            barra2B.vertices = (G*b02B')'+ T;
            
            patch(app.UIAxes, barra2B, 'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Triangulo
            % Ajuste ejes pieza
            G = [0 0 1; 1 0 0; 0 1 0];
            % Posicionamiento y traslacion
            T = [(q1-17) (cos(q2r)*300-56) (sin(q2r)*300)];
            
            triangulo.vertices = (G*t')'+ T;
            
            patch(app.UIAxes, triangulo,'FaceColor',    [0.8500, 0.3250, 0.0980], ...
                'EdgeColor',       'none',        ...
                'FaceLighting',    'gouraud',     ...
                'AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Toolhead
            % Ajuste ejes pieza
            G = [1 0 0; 0 cos(90*pi/180) -sin(90*pi/180); 0 sin(90*pi/180) cos(90*pi/180)];
            % Posicionamiento y traslacion
            T = [(q1-32) (cos(q2r)*300+cos(q3r)*300+75) (sin(q3r)*300+sin(q2r)*300-10)];
            
            toolhead.vertices = (G*th')'+ T;
            
            patch(app.UIAxes, toolhead,'FaceColor',    [0.8500, 0.3250, 0.0980], ...
                'EdgeColor',       'none',        ...
                'FaceLighting',    'gouraud',     ...
                'AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Patin
            % Ajuste ejes pieza
            G = [1 0 0; 0 0 -1; 0 1 0];
            % Posicionamiento y traslacion
            T = [(q1-185/2) 20 -120];
            
            patin.vertices = (G*p')'+ T;
            
            patch(app.UIAxes, patin,'FaceColor',  [0.8500, 0.3250, 0.0980],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
            
            %% Base
            % Ajuste ejes pieza
            G = [1 0 0; 0 0 -1; 0 1 0];
            % Posicionamiento y traslacion
            T = [(-250/2) 380 -145];
            
            base.vertices = (G*ba')'+ T;
            
            patch(app.UIAxes, base,'FaceColor',[1 0.8 0.6],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
            material(app.UIAxes,'dull');
    
            %% Dibujo
            if (app.light==0)
                camlight(app.UIAxes,'headlight');
                app.light=1;
            end
            
            %% Titulos
            xlabel(app.UIAxes, 'x(mm)');
            ylabel(app.UIAxes, 'y(mm)');
            zlabel(app.UIAxes, 'z(mm)');
            
            % PLOT
            
            
        end
        
        function graficasQT(app,T,p,x0,xf)
            
            t = linspace(0,T,p);
            tt = linspace(0,T);
            
            x1 = linspace(x0(1),xf(1),p);
            x2 = linspace(x0(2),xf(2),p);
            x3 = linspace(x0(3),xf(3),p);
            
            [q1,q2,q3] = inversa(x1,x2,x3);
            
            q2 = rad2deg(q2);
            q3 = rad2deg(q3);
            
            
            pp1 = spline(t,[0 q1 0]);
            pp2 = spline(t,[0 q2 0]);
            pp3 = spline(t,[0 q3 0]);
            
            plot(app.q1t,t,q1,'o',tt,ppval(pp1,tt),'-');
            %grid(app.q1t,'on');
            xlabel(app.q1t,'t [s]');
            ylabel(app.q1t,'q1 [mm]');
             
            plot(app.q2t,t,q2,'o',tt,ppval(pp2,tt),'-');
            %grid(app.q2t,'on');
            xlabel(app.q2t,'t [s]');
            ylabel(app.q2t,'q2 [deg]');
 
            plot(app.q3t,t,q3,'o',tt,ppval(pp3,tt),'-');
            %grid(app.q3t,'on');
            xlabel(app.q3t,'t [s]');
            ylabel(app.q3t,'q3 [deg]');


            % Gráficas xyz con puntos de interpolación
            figure
            [x,y,z] = directa(ppval(pp1,tt),deg2rad(ppval(pp2,tt)),deg2rad(ppval(pp3,tt)));
            plot3(x,y,z,'-b');
            axis([0 inf 0 inf 0 inf]);
            xlabel 'x(mm)';
            ylabel 'y(mm)';
            zlabel 'z(mm)';
            hold on
            plot3(x1,x2,x3,'or');
            grid on
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Puerto.Items=seriallist;
            
            app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
            app.Baudrate.Value = {'115200'};
            main(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            try
                mandarComando(app,'J0');
                set(app.HomeButton,'BackgroundColor',[0.6 1 0.6]);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Detener timer
            try
                stop(app.myTimer);
                delete(app.myTimer);
            catch
            end
            % Detener conexion serie
            try
                fclose(app.arduino);
                delete(app.UIFigure);
            catch
                delete(app.UIFigure);
            end
            
        end

        % Value changed function: Baudrate, Puerto
        function PuertoValueChanged(app, event)
            try
                app.arduino=serial(app.Puerto.Value,'BaudRate', str2double(app.Baudrate.Value));
                fopen(app.arduino);
                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end

        end

        % Value changed function: SteppersSwitch
        function SteppersSwitchValueChanged(app, event)
            try
                if (app.SteppersSwitch.Value == "On") 
                      [q1,q2,q3] = inversa(app.nowX,app.nowY,app.nowZ);
                      mandarComando(app, sprintf('J1 M%d %3.3f\n', 1, q1));
                      mandarComando(app, sprintf('J1 M%d %3.3f\n', 2, rad2deg(q2)));
                      mandarComando(app, sprintf('J1 M%d %3.3f\n', 3, rad2deg(q3)));

                end
                mandarComando(app,'J3');
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Value changed function: V2
        function V2ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 2, app.V2.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: V3
        function V3ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 3,app.V3.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: V1
        function V1ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 1, app.V1.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: CadenaSwitch
        function CadenaSwitchValueChanged(app, event)
            main(app);
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            delete(instrfindall);
            
            try
                app.arduino=serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
                app.arduino.Terminator = 'LF';
                app.arduino.OutputBufferSize = 1024;
                app.arduino.BytesAvailableFcnMode =  'terminator';
                app.arduino.BytesAvailableFcn = @(~,~) app.serialEvent;
                fopen(app.arduino);

                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.DesconectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                pause(2);
                
            catch 
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end


            try
                app.myTimer = timer('TimerFcn', @(~,~) app.consultarAngulos, ...
                    'ExecutionMode', 'fixedRate');
                app.myTimer.Period = 0.5;
                %start(app.myTimer)
            catch
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.V1.Value=0;
            app.V2.Value=0;
            app.V3.Value=0;
            app.posq1.Value=100;
            app.posq2.Value=55;
            app.posq3.Value=-50;
            
            try
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 2, app.V2.Value)); pause(0.1);
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 1, app.V1.Value)); pause(0.1);
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 3, app.V3.Value)); pause(0.1);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end

        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            app.Puerto.Items=seriallist;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            try
                fclose(app.arduino);
                set(app.DesconectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.ConectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                set(app.HomeButton,'BackgroundColor','red');
                
            catch
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            try
                mandarComando(app, sprintf('J4 M%d %3.3f\n', 1, app.MS.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            try
                mandarComando(app, sprintf('J4 M%d %3.3f\n', 1, -app.MS.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Value changed function: posq1
        function posq1ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 1, app.posq1.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Value changed function: posq2
        function posq2ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 2, app.posq2.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Value changed function: posq3
        function posq3ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 3, app.posq3.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            try
                x=[app.XSpinner.Value app.YSpinner.Value app.ZSpinner.Value];
                if constrains(x) == 1
                    app.Lamp_Tr.Color = 'Green';
                    x0 = [app.nowX app.nowY app.nowZ];
                    app.timeS = second(datetime('now'));
                    pause(0.01);
                    moveCartesianas(app,x0,x,app.TiempoSpinner.Value,app.PuntosSpinner.Value);
                    graficasQT(app,app.TiempoSpinner.Value,app.PuntosSpinner.Value,x0,x);
                    app.q1tr=NaN(100,1);
                    app.q2tr=NaN(100,1);
                    app.q3tr=NaN(100,1);
                    app.ttr=NaN(100,1);
                    tic
                else
                    app.Lamp_Tr.Color='Red';
                end
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end    
        end

        % Callback function
        function ModoDropDownValueChanged(app, event)
            
            if app.ModoDropDown.Value=="Interpolador Cartesiano"
                app.PuntosEditFieldLabel.Text = 'Puntos';
                app.PrecisionmmEditField.Enable = 'off';
                app.Tiempo.Enable =  'on';
            end
            
            if app.ModoDropDown.Value=="Interpolador Articular"
                app.PuntosEditFieldLabel.Text = 'Velocidad';
                app.PrecisionmmEditField.Enable =  'on';
                app.Tiempo.Enable =  'off';
            end
            
            if app.ModoDropDown.Value=="Jacobiana"
                app.PuntosEditFieldLabel.Text = 'Velocidad';
                app.PrecisionmmEditField.Enable =  'on';
                app.Tiempo.Enable =  'off';
            end

            
        end

        % Value changed function: posq4
        function posq4ValueChanged(app, event)
           if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 4, app.posq4.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Off"
                   mandarComando(app, sprintf('J4 M%d %3.3f\n', 2, 0));
            else
                   mandarComando(app, sprintf('J4 M%d %3.3f\n', 2, 255));
            end
        end

        % Value changed function: posq1_2
        function posq1_2ValueChanged(app, event)
            value = app.posq1_2.Value;
            x=[value app.nowY app.nowZ];
            if constrains(x) == 1
                app.Lamp_Tr.Color = 'Green';
                x0 = [app.nowX app.nowY app.nowZ];
                app.timeS = second(datetime('now'));
                pause(0.01);

                moveCartesianas(app,x0,x,5,4);
                graficasQT(app,5,4,x0,x);
            else
                app.Lamp_Tr.Color='Red';
            end
            
        end

        % Value changed function: posq2_2
        function posq2_2ValueChanged(app, event)
            value = app.posq2_2.Value;
            x=[app.nowX value app.nowZ];
            if constrains(x) == 1
                app.Lamp_Tr.Color = 'Green';
                x0 = [app.nowX app.nowY app.nowZ];
                app.timeS = second(datetime('now'));
                pause(0.01);

                moveCartesianas(app,x0,x,5,4);
                graficasQT(app,5,4,x0,x);
            else
                app.Lamp_Tr.Color='Red';
            end            
        end

        % Value changed function: posq3_2
        function posq3_2ValueChanged(app, event)
            value = app.posq3_2.Value;
            x=[app.nowX app.nowY value];
            if constrains(x) == 1
                app.Lamp_Tr.Color = 'Green';
                x0 = [app.nowX app.nowY app.nowZ];
                app.timeS = second(datetime('now'));
                pause(0.01);

                moveCartesianas(app,x0,x,5,4);
                graficasQT(app,5,4,x0,x);
            else
                app.Lamp_Tr.Color='Red';
            end   
        end

        % Button pushed function: IniciarGuiadoButton
        function IniciarGuiadoButtonPushed(app, event)
            try
                mandarComando(app, 'J6');
                app.f=fopen('puntos.txt','a+');
                app.IniciarGuiadoButton.Text = 'Iniciar Guiado';
                app.MarcarPuntoButton.Enable = 'on';
                app.EliminarPuntoAnteriorButton.Enable = 'on';
                set(app.IniciarGuiadoButton,'BackgroundColor',[0.6 1 0.6]);
                if app.IniciarGuiadoButton.Text == "Iniciar Guiado"
                app.IniciarGuiadoButton.Text = 'Finalizar Guiado';
                end
                if app.IniciarGuiadoButton.Text == "Finalizar Guiado"
                app.IniciarGuiadoButton.Text = 'Iniciar Guiado';
                end
            catch
                app.IniciarGuiadoButton.Text = 'Error de Conexión';
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    set(app.IniciarGuiadoButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    set(app.IniciarGuiadoButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Button pushed function: MarcarPuntoButton
        function MarcarPuntoButtonPushed(app, event)
            fprintf(app.f,'%d',app.app.nowX,'%d',app.nowY,'%d\n',app.nowZ);
            app.PuntosMarcadosTextArea.Value = fileread('puntos.txt');
        end

        % Button pushed function: IniciarMovimientoButton
        function IniciarMovimientoButtonPushed(app, event)
            fclose(f);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.HomeButton = uibutton(app.PaneldecontrolTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.CadenaabiertaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenaabiertaPanel.Title = 'Cadena abierta';
            app.V2 = uislider(app.CadenaabiertaPanel);
            app.V2.Limits = [-10 10];
            app.V2.ValueChangedFcn = createCallbackFcn(app, @V2ValueChanged, true);
            app.V2.Enable = 'off';
            app.V3 = uislider(app.CadenaabiertaPanel);
            app.V3.Limits = [-10 10];
            app.V3.ValueChangedFcn = createCallbackFcn(app, @V3ValueChanged, true);
            app.V3.Enable = 'off';
            app.V1 = uislider(app.CadenaabiertaPanel);
            app.V1.Limits = [-30 30];
            app.V1.ValueChangedFcn = createCallbackFcn(app, @V1ValueChanged, true);
            app.V1.Enable = 'off';
            app.q1mmsLabel = uilabel(app.CadenaabiertaPanel);
            app.q1mmsLabel.Enable = 'off';
            app.q1mmsLabel.Text = 'q1 (mm/s)';
            app.q2gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q2gradsLabel.Enable = 'off';
            app.q2gradsLabel.Text = 'q2 (grad/s)';
            app.q3gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q3gradsLabel.Enable = 'off';
            app.q3gradsLabel.Text = 'q3 (grad/s)';
            app.STOPButton = uibutton(app.CadenaabiertaPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CadenacerradaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenacerradaPanel.Title = 'Cadena cerrada';
            app.q1mmLabel = uilabel(app.CadenacerradaPanel);
            app.q1mmLabel.Enable = 'off';
            app.q1mmLabel.Text = 'q1 (mm)';
            app.q2degLabel = uilabel(app.CadenacerradaPanel);
            app.q2degLabel.Enable = 'off';
            app.q2degLabel.Text = 'q2 (deg)';
            app.q3degLabel = uilabel(app.CadenacerradaPanel);
            app.q3degLabel.Enable = 'off';
            app.q3degLabel.Text = 'q3 (deg)';
            app.posq1 = uislider(app.CadenacerradaPanel);
            app.posq1.Limits = [0 240];
            app.posq1.ValueChangedFcn = createCallbackFcn(app, @posq1ValueChanged, true);
            app.posq1.Enable = 'off';
            app.posq1.Value = 110;
            app.posq2 = uislider(app.CadenacerradaPanel);
            app.posq2.ValueChangedFcn = createCallbackFcn(app, @posq2ValueChanged, true);
            app.posq2.Enable = 'off';
            app.posq2.Value = 45;
            app.posq3 = uislider(app.CadenacerradaPanel);
            app.posq3.Limits = [-110 10];
            app.posq3.ValueChangedFcn = createCallbackFcn(app, @posq3ValueChanged, true);
            app.posq3.Enable = 'off';
            app.posq3.Value = -45;
            app.posq4 = uislider(app.CadenacerradaPanel);
            app.posq4.Limits = [0 180];
            app.posq4.ValueChangedFcn = createCallbackFcn(app, @posq4ValueChanged, true);
            app.posq4.Enable = 'off';
            app.posq4.Value = 90;
            app.q4degLabel = uilabel(app.CadenacerradaPanel);
            app.q4degLabel.Enable = 'off';
            app.q4degLabel.Text = 'q4 (deg)';
            app.UIAxes = uiaxes(app.PaneldecontrolTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-70 400];
            app.UIAxes.YLim = [-200 600];
            app.UIAxes.ZLim = [-250 400];
            app.ComunicacinPanel = uipanel(app.PaneldecontrolTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Puerto.Value = {};
            app.ConectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.RButton_2 = uibutton(app.ComunicacinPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'R';
            app.CartesianasPanel = uipanel(app.PaneldecontrolTab);
            app.CartesianasPanel.Title = 'Cartesianas';
            app.XEditFieldLabel = uilabel(app.CartesianasPanel);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.CartesianasPanel, 'numeric');
            app.X.Editable = 'off';
            app.YLabel_3 = uilabel(app.CartesianasPanel);
            app.YLabel_3.Text = 'Y';
            app.Y = uieditfield(app.CartesianasPanel, 'numeric');
            app.Y.Editable = 'off';
            app.ZLabel_3 = uilabel(app.CartesianasPanel);
            app.ZLabel_3.Text = 'Z';
            app.Z = uieditfield(app.CartesianasPanel, 'numeric');
            app.Z.Editable = 'off';
            app.CadenaSwitch = uiswitch(app.PaneldecontrolTab, 'toggle');
            app.CadenaSwitch.Items = {'Cerrada', 'Abierta'};
            app.CadenaSwitch.ValueChangedFcn = createCallbackFcn(app, @CadenaSwitchValueChanged, true);
            app.CadenaSwitch.Value = 'Cerrada';
            app.VentosaPanel = uipanel(app.PaneldecontrolTab);
            app.VentosaPanel.Title = 'Ventosa';
            app.Switch = uiswitch(app.VentosaPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.GarraPanel = uipanel(app.PaneldecontrolTab);
            app.GarraPanel.Title = 'Garra';
            app.AbrirButton = uibutton(app.GarraPanel, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.CerrarButton = uibutton(app.GarraPanel, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.MS = uieditfield(app.GarraPanel, 'numeric');
            app.MS.Value = 100;
            app.msLabel = uilabel(app.GarraPanel);
            app.msLabel.Text = 'ms';
            app.SteppersSwitchLabel = uilabel(app.PaneldecontrolTab);
            app.SteppersSwitchLabel.Text = 'Steppers';
            app.SteppersSwitch = uiswitch(app.PaneldecontrolTab, 'slider');
            app.SteppersSwitch.ValueChangedFcn = createCallbackFcn(app, @SteppersSwitchValueChanged, true);
            app.SteppersSwitch.Value = 'On';
            app.LinearectaapuntoencartesianasPanel = uipanel(app.PaneldecontrolTab);
            app.LinearectaapuntoencartesianasPanel.Title = 'Linea recta a punto en cartesianas';
            app.EnviarButton = uibutton(app.LinearectaapuntoencartesianasPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.XSpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.XSpinner.ValueDisplayFormat = '%.0f';
            app.XSpinner.Value = 110;
            app.YSpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.YSpinner.ValueDisplayFormat = '%.0f';
            app.YSpinner.Value = 424;
            app.ZSpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.ZSpinner.ValueDisplayFormat = '%.0f';
            app.PuntosSpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.PuntosSpinnerLabel.Text = 'Puntos';
            app.PuntosSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.PuntosSpinner.ValueDisplayFormat = '%.0f';
            app.PuntosSpinner.Value = 4;
            app.TiempoSpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.TiempoSpinnerLabel.Text = 'Tiempo';
            app.TiempoSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.TiempoSpinner.ValueDisplayFormat = '%.0f';
            app.TiempoSpinner.Value = 5;
            app.SpinnerLabel = uilabel(app.LinearectaapuntoencartesianasPanel);
            app.SpinnerLabel.Text = 'α';
            app.alfaSpinner = uispinner(app.LinearectaapuntoencartesianasPanel);
            app.alfaSpinner.ValueDisplayFormat = '%.0f';
            app.alfaSpinner.Value = 90;
            app.ArticularesPanel = uipanel(app.PaneldecontrolTab);
            app.ArticularesPanel.Title = 'Articulares';
            app.q1mmLabel_2 = uilabel(app.ArticularesPanel);
            app.q1mmLabel_2.Text = 'q1(mm)';
            app.q1p = uieditfield(app.ArticularesPanel, 'numeric');
            app.q1p.Editable = 'off';
            app.q2degLabel_2 = uilabel(app.ArticularesPanel);
            app.q2degLabel_2.Text = 'q2(deg)';
            app.q2p = uieditfield(app.ArticularesPanel, 'numeric');
            app.q2p.Editable = 'off';
            app.q3degLabel_2 = uilabel(app.ArticularesPanel);
            app.q3degLabel_2.Text = 'q3(deg)';
            app.q3p = uieditfield(app.ArticularesPanel, 'numeric');
            app.q3p.Editable = 'off';
            app.CadenacerradaPanel_2 = uipanel(app.PaneldecontrolTab);
            app.CadenacerradaPanel_2.Title = 'Cadena cerrada';
            app.xmmLabel = uilabel(app.CadenacerradaPanel_2);
            app.xmmLabel.Enable = 'off';
            app.xmmLabel.Text = 'x (mm)';
            app.ydegLabel = uilabel(app.CadenacerradaPanel_2);
            app.ydegLabel.Enable = 'off';
            app.ydegLabel.Text = 'y (deg)';
            app.zdegLabel = uilabel(app.CadenacerradaPanel_2);
            app.zdegLabel.Enable = 'off';
            app.zdegLabel.Text = 'z (deg)';
            app.posq1_2 = uislider(app.CadenacerradaPanel_2);
            app.posq1_2.Limits = [0 250];
            app.posq1_2.ValueChangedFcn = createCallbackFcn(app, @posq1_2ValueChanged, true);
            app.posq1_2.Enable = 'off';
            app.posq1_2.Value = 110;
            app.posq2_2 = uislider(app.CadenacerradaPanel_2);
            app.posq2_2.Limits = [150 550];
            app.posq2_2.ValueChangedFcn = createCallbackFcn(app, @posq2_2ValueChanged, true);
            app.posq2_2.Enable = 'off';
            app.posq2_2.Value = 424;
            app.posq3_2 = uislider(app.CadenacerradaPanel_2);
            app.posq3_2.Limits = [-40 400];
            app.posq3_2.ValueChangedFcn = createCallbackFcn(app, @posq3_2ValueChanged, true);
            app.posq3_2.Enable = 'off';
            app.SetTCPPanel = uipanel(app.PaneldecontrolTab);
            app.SetTCPPanel.Title = 'Set TCP';
            app.GarraButton = uibutton(app.SetTCPPanel, 'push');
            app.GarraButton.Text = 'Garra';
            app.VentosaButton = uibutton(app.SetTCPPanel, 'push');
            app.VentosaButton.Text = 'Ventosa';
            app.ErrorPanel = uipanel(app.PaneldecontrolTab);
            app.ErrorPanel.Title = 'Error';
            app.Lamp_Tr = uilamp(app.ErrorPanel);
            app.BaudrateDropDownLabel = uilabel(app.PaneldecontrolTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.PaneldecontrolTab);
            app.Baudrate.Items = {};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Baudrate.Value = {};
            app.q1t = uiaxes(app.PaneldecontrolTab);
            xlabel(app.q1t, 't')
            ylabel(app.q1t, 'q1')
            app.q2t = uiaxes(app.PaneldecontrolTab);
            xlabel(app.q2t, 't')
            ylabel(app.q2t, 'q2')
            app.q3t = uiaxes(app.PaneldecontrolTab);
            xlabel(app.q3t, 't')
            ylabel(app.q3t, 'q3')
            app.PendantTab = uitab(app.TabGroup);
            app.PendantTab.Title = 'Pendant';
            app.IniciarGuiadoButton = uibutton(app.PendantTab, 'push');
            app.IniciarGuiadoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarGuiadoButtonPushed, true);
            app.IniciarGuiadoButton.Text = 'Iniciar Guiado';
            app.Panel = uipanel(app.PendantTab);
            app.MarcarPuntoButton = uibutton(app.Panel, 'push');
            app.MarcarPuntoButton.ButtonPushedFcn = createCallbackFcn(app, @MarcarPuntoButtonPushed, true);
            app.MarcarPuntoButton.Enable = 'off';
            app.MarcarPuntoButton.Text = 'Marcar Punto';
            app.EliminarPuntoAnteriorButton = uibutton(app.Panel, 'push');
            app.EliminarPuntoAnteriorButton.Enable = 'off';
            app.EliminarPuntoAnteriorButton.Text = 'Eliminar Punto Anterior';
            app.PosicinPanel = uipanel(app.PendantTab);
            app.PosicinPanel.Title = 'Posición';
            app.CartesianasPanel_2 = uipanel(app.PosicinPanel);
            app.CartesianasPanel_2.Title = 'Cartesianas';
            app.XEditFieldLabel_2 = uilabel(app.CartesianasPanel_2);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_2 = uieditfield(app.CartesianasPanel_2, 'numeric');
            app.X_2.Editable = 'off';
            app.YLabel_4 = uilabel(app.CartesianasPanel_2);
            app.YLabel_4.Text = 'Y';
            app.Y_2 = uieditfield(app.CartesianasPanel_2, 'numeric');
            app.Y_2.Editable = 'off';
            app.ZLabel_4 = uilabel(app.CartesianasPanel_2);
            app.ZLabel_4.Text = 'Z';
            app.Z_2 = uieditfield(app.CartesianasPanel_2, 'numeric');
            app.Z_2.Editable = 'off';
            app.Label_2 = uilabel(app.CartesianasPanel_2);
            app.Label_2.Text = 'α';
            app.alfa_2 = uieditfield(app.CartesianasPanel_2, 'numeric');
            app.alfa_2.Editable = 'off';
            app.ArticularesPanel_2 = uipanel(app.PosicinPanel);
            app.ArticularesPanel_2.Title = 'Articulares';
            app.q3degLabel_3 = uilabel(app.ArticularesPanel_2);
            app.q3degLabel_3.Text = 'q3(deg)';
            app.q3p_2 = uieditfield(app.ArticularesPanel_2, 'numeric');
            app.q3p_2.Editable = 'off';
            app.q1mmLabel_3 = uilabel(app.ArticularesPanel_2);
            app.q1mmLabel_3.Text = 'q1(mm)';
            app.q1p_2 = uieditfield(app.ArticularesPanel_2, 'numeric');
            app.q1p_2.Editable = 'off';
            app.q2degLabel_3 = uilabel(app.ArticularesPanel_2);
            app.q2degLabel_3.Text = 'q2(deg)';
            app.q2p_2 = uieditfield(app.ArticularesPanel_2, 'numeric');
            app.q2p_2.Editable = 'off';
            app.q4degLabel_3 = uilabel(app.ArticularesPanel_2);
            app.q4degLabel_3.Text = 'q4(deg)';
            app.q4p_2 = uieditfield(app.ArticularesPanel_2, 'numeric');
            app.q4p_2.Editable = 'off';
            app.Panel_2 = uipanel(app.PendantTab);
            app.PuntosmarcadosTextAreaLabel = uilabel(app.Panel_2);
            app.PuntosmarcadosTextAreaLabel.Text = 'Puntos marcados';
            app.PuntosmarcadosTextArea = uitextarea(app.Panel_2);
            app.PuntosmarcadosTextArea.Editable = 'off';
            app.IniciarMovimientoButton = uibutton(app.Panel_2, 'push');
            app.IniciarMovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarMovimientoButtonPushed, true);
            app.IniciarMovimientoButton.Text = 'Iniciar Movimiento';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end